classdef GNSS_R_pool_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CurrenttimeButton           matlab.ui.control.Button
        DateYYYMMDDEditField        matlab.ui.control.EditField
        DateYYYMMDDEditFieldLabel   matlab.ui.control.Label
        secsEditField               matlab.ui.control.NumericEditField
        secsEditFieldLabel          matlab.ui.control.Label
        minsEditField               matlab.ui.control.NumericEditField
        minsEditFieldLabel          matlab.ui.control.Label
        hsEditField                 matlab.ui.control.NumericEditField
        hsEditFieldLabel            matlab.ui.control.Label
        TimeofdayLabel              matlab.ui.control.Label
        DirectiondegEditField       matlab.ui.control.NumericEditField
        DirectiondegEditFieldLabel  matlab.ui.control.Label
        DistancemEditField          matlab.ui.control.NumericEditField
        DistancemEditFieldLabel     matlab.ui.control.Label
        HeightmEditField            matlab.ui.control.NumericEditField
        HeightmEditFieldLabel       matlab.ui.control.Label
        SkyplotPanel                matlab.ui.container.Panel
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label                       matlab.ui.control.Label
        OpcinDropDown               matlab.ui.control.DropDown
        OpcinDropDownLabel          matlab.ui.control.Label
        UIAxes3                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        gps_pos         % GPS satellites' position - ECEF
        satIdx          % List of satellites SVID
        gpsData         % gps orbital data from ephemeris
        num_gps_sats    % number of gps satellites
        s10             % Index vector - selects sats in view with el>10°
        az0             % Rx antenna beam azimuth pointing direction
        rx_lla          % Rx position - LLA
        theta_x         % Rx antenna beam aperture angle - x dim
        theta_y         % Rx antenna beam aperture angle - y dim
        az              % Azimuth values for satellites in view
        elev            % Elevation values for satellites in view
        el_min_pool     % minimum elevation of SP within pool surface
        el_max_pool     % maximum elevation of SP within pool surface
        aperture_pool   % azumith aperture given by the pool limits
        pax             % polar axes
        timezone_os     % time zone offset
    end

    methods (Access = private)
       
        % Updates skyplot and pool plot
        function updateplot(app)


            % Necessary variables for plots

            d = app.DistancemEditField.Value; % Distance from the Rx antenna to the closest pool edge [m]
            h = app.HeightmEditField.Value; % Rx antenna receiver height [m]
            
            % Pool dimensions, the Rx is located at the middle point of on 
            % of the sides of the pool (those with length=pool_length)
            pool_length = 55;
            pool_width = 15;
            app.el_max_pool = atan(h/d)*180/pi;
            app.el_min_pool = atan(h/(d+pool_width))*180/pi;
            app.aperture_pool = atan(pool_length/2/(d+pool_width))*180/pi;

            % Min and max elevation values of captured reflections
            delta_el = app.theta_x;
            beta0 = app.DirectiondegEditField.Value;
            el_min = max(90-beta0-delta_el, app.el_min_pool);
            el_max = min(90-beta0+delta_el, app.el_max_pool);

            % Min and max azimuth values of captured reflections
            delta_az = min(app.aperture_pool, app.theta_y);
            az_min = app.az0 - delta_az;
            az_max = app.az0 + delta_az;


            % skyplot: polar plot with the position of the satellites in
            % view. Also plots the region of the sky that is captured by
            % the reflectometry Rx antenna.
            for ss = 1:length(app.s10)
                polarplot(app.pax,pi/2-pi/180*app.az(app.s10(ss)),90-app.elev(app.s10(ss)),'o');hold(app.pax,'on')
                text(app.pax,pi/2-pi/180*app.az(app.s10(ss)),90-app.elev(app.s10(ss)),"  "+int2str(app.satIdx(app.s10(ss))))
            end

            a1 = az_min:0.1:az_max;
            a2 = el_min:0.1:el_max;

            polarplot(app.pax,pi/2-pi/180*a1,90-el_min*ones(size(a1)));
            polarplot(app.pax,pi/2-pi/180*a1,90-el_max*ones(size(a1)));
            polarplot(app.pax,pi/2-pi/180*a1,beta0*ones(size(a1)),'color','g',"LineStyle","--");
            polarplot(app.pax,pi/2-pi/180*az_min*ones(size(a2)),90-a2);
            polarplot(app.pax,pi/2-pi/180*az_max*ones(size(a2)),90-a2);
            hold(app.pax,'off')


            % pool plot: plots position of the receiver antenna relative to 
            % the pool (side view). Also plots its aperture, maximum and
            % minimum elevation angles of captured reflected signals and
            % maximum gain direction

            l1 = -4; l2 = 21; l3 = -2; l4 = 8; % Pool plot axis limits

            if strcmp(app.OpcinDropDown.Value, 'SE')
                plot(app.UIAxes3,0,h,'*r'),
                line(app.UIAxes3,[d d+pool_width], [0 0],'linewidth',3);
                line(app.UIAxes3,[0 h*tan((90-el_min)*pi/180)], [h 0])
                line(app.UIAxes3,[0 h*tan((90-el_max)*pi/180)], [h 0])
                line(app.UIAxes3,[0 h*tan((beta0-delta_el)*pi/180)], [h 0],'Color','red','LineStyle','--')
                line(app.UIAxes3,[0 h*tan((beta0+delta_el)*pi/180)], [h 0],'Color','red','LineStyle','--')
                line(app.UIAxes3,[0 h*tan((beta0)*pi/180)], [h 0],'Color','green','LineStyle','--')
            else
                plot(app.UIAxes3,pool_width+d*2,h,'*r'),
                line(app.UIAxes3,[d d+pool_width], [0 0],'linewidth',3);                
                line(app.UIAxes3,[pool_width+d*2 pool_width+d*2-h*tan((90-el_min)*pi/180)], [h 0])
                line(app.UIAxes3,[pool_width+d*2 pool_width+d*2-h*tan((90-el_max)*pi/180)], [h 0])
                line(app.UIAxes3,[pool_width+d*2 pool_width+d*2-h*tan((beta0-delta_el)*pi/180)], [h 0],'Color','red','LineStyle','--')
                line(app.UIAxes3,[pool_width+d*2 pool_width+d*2-h*tan((beta0+delta_el)*pi/180)], [h 0],'Color','red','LineStyle','--')
                line(app.UIAxes3,[pool_width+d*2 pool_width+d*2-h*tan((beta0)*pi/180)], [h 0],'Color','green','LineStyle','--')
            end
            
            axis(app.UIAxes3,[l1 l2 l3 l4])

            app.Label.Text = sprintf('Minimum elevation: %.3g°',el_min);
            app.Label_2.Text = sprintf('Maximum elevation: %.3g°',el_max);
            visibles = find((app.elev(:)>app.el_min_pool).*(app.elev(:)<app.el_max_pool).*(app.az(:)<app.az0+delta_az).*(app.az(:)>app.az0-delta_az));
            [~, ord_idx] = sort(app.elev(visibles));
            visibles = visibles(ord_idx);
            vis_txt = "Satellites in view:\n";
            for ii = 1:length(visibles)
                vis_txt = vis_txt + sprintf("SVID %i: %.3g° (%.3g°)\n", app.satIdx(visibles(ii)), app.elev(visibles(ii)), 90-app.elev(visibles(ii)));
            end
            vis_txt = compose(vis_txt);
            app.Label_3.Text = vis_txt;
            drawnow
        end


        % Updates the variables used in the skyplot (gps sats positions)
        function updateSkyplotVars(app)

            % Observation time
            obs_hour = app.hsEditField.Value;
            obs_mins = app.minsEditField.Value;
            obs_secs = app.secsEditField.Value;
            Y = str2double(app.DateYYYMMDDEditField.Value(1:4));
            M = str2double(app.DateYYYMMDDEditField.Value(6:7));
            D = str2double(app.DateYYYMMDDEditField.Value(9:10));
            t_obs = datetime([Y,M,D,obs_hour + app.timezone_os,obs_mins,obs_secs]);

            % Updates GPS satellites positions, azimuth and elevations,
            % filter out those with el<10°
            app.az = zeros(1, app.num_gps_sats);
            app.elev = zeros(1, app.num_gps_sats);
            wgs = wgs84Ellipsoid('kilometers');
            [app.gps_pos, ~, ~] = gnssconstellation(t_obs, app.gpsData, GNSSFileType="RINEX");
            [app.az, app.elev, ~] = ecef2aer(app.gps_pos(:,1), app.gps_pos(:,2), app.gps_pos(:,3), app.rx_lla(1),app.rx_lla(2),app.rx_lla(3),wgs);
            app.s10 = find(app.elev>10);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)


            % Load GPS epehemeris file
            rinex_filename = 'ANK200TUR_S_20232960000_01D_GN.rnx'; % filename: GPS rinex file
            data = rinexread(rinex_filename);
            app.gpsData = data.GPS;
            [~, app.satIdx] = unique(app.gpsData.SatelliteID);
            app.gpsData = app.gpsData(app.satIdx,:);
            app.num_gps_sats = length(app.satIdx); % number of GPS satellites in the simulation

            % Polar axes for skyplot
            app.pax = polaraxes(app.SkyplotPanel);

            % Default obs_time is current time
            current_date_time = datetime('now');
            app.hsEditField.Value = current_date_time.Hour;
            app.minsEditField.Value = current_date_time.Minute;
            app.secsEditField.Value = round(current_date_time.Second);
            date0 = string(current_date_time.Year);
            if current_date_time.Month<10
                date0 = date0 + '/0'+string(current_date_time.Month);
            else
                date0 = date0 + '/' + string(current_date_time.Month);
            end
            date0 = date0 + '/' + string(current_date_time.Day);
            app.DateYYYMMDDEditField.Value = date0;

            % Time zone offset
            app.timezone_os = 3;  % UTC -3 (Buenos Aires timezone)


            % Observation time
            t_obs = current_date_time + app.timezone_os/24;

            % Rx antenna position and orientation
            app.rx_lla = [-34.90523333 -57.94074167 2]; % Antenna position: UNLP pool
            app.az0 = 130; % Azimuth pointing direction: UNLP pool

            % Antenna pattern
            app.theta_x = 14.45;app.theta_y = 42.5; % Antena beam aperture angles (3dB angles) [deg]

            % Initializes GPS satellites positions, azimuth and elevations,
            % filter out those with el<10°
            app.az = zeros(1, app.num_gps_sats);
            app.elev = zeros(1, app.num_gps_sats);
            wgs = wgs84Ellipsoid('kilometers');
            [app.gps_pos, ~, ~] = gnssconstellation(t_obs, app.gpsData, GNSSFileType="RINEX");
            [app.az, app.elev, ~] = ecef2aer(app.gps_pos(:,1), app.gps_pos(:,2), app.gps_pos(:,3), app.rx_lla(1),app.rx_lla(2),app.rx_lla(3),wgs);
            app.s10 = find(app.elev>10);

           
            % Default initial values
            app.HeightmEditField.Value = app.rx_lla(3);
            app.DistancemEditField.Value = 1;
            app.DirectiondegEditField.Value = 65;
            updateplot(app)
        end

        % Value changed function: OpcinDropDown
        function OpcinDropDownValueChanged(app, event)
            % Changes pointing azimuth value if maixmum gain direction is
            % southeast or northwest (UNLP pool)

            value = app.OpcinDropDown.Value;
            if strcmp(value, 'SE')
                app.az0 = 130;
            else
                app.az0 = 130+180;
            end
            updateplot(app)
        end

        % Value changed function: HeightmEditField
        function HeightmEditFieldValueChanged(app, event)
            % updates position and plots if Rx height is changed
            app.rx_lla = [-34.90523333 -57.94074167 app.HeightmEditField.Value];
            updateSkyplotVars(app)
            updateplot(app)

        end

        % Value changed function: DistancemEditField
        function DistancemEditFieldValueChanged(app, event)
            % updates plots if Rx distance to pool is changed
            updateplot(app)

        end

        % Value changed function: DirectiondegEditField
        function DirectiondegEditFieldValueChanged(app, event)
            % updates plots if Rx antenna pointing direction is changed
            updateplot(app)

        end

        % Value changed function: hsEditField
        function hsEditFieldValueChanged(app, event)
            % updates plots if obs_time hours is changed
            updateSkyplotVars(app)
            updateplot(app)

        end

        % Value changed function: minsEditField
        function minsEditFieldValueChanged(app, event)
            % updates plots if obs_time mins is changed
            updateSkyplotVars(app)
            updateplot(app)

        end

        % Value changed function: secsEditField
        function secsEditFieldValueChanged(app, event)
            % updates plots if obs_time secs is changed
            updateSkyplotVars(app)
            updateplot(app)

        end

        % Value changed function: DateYYYMMDDEditField
        function DateYYYMMDDEditFieldValueChanged(app, event)
            % updates plots if obs_time date is changed
            updateSkyplotVars(app)
            updateplot(app)
        end

        % Button pushed function: CurrenttimeButton
        function CurrenttimeButtonPushed(app, event)
            % updates values and plots if obs_time is set to current time 
            % by pressing the current time button

            current_date_time = datetime('now');
            app.hsEditField.Value = current_date_time.Hour;
            app.minsEditField.Value = current_date_time.Minute;
            app.secsEditField.Value = round(current_date_time.Second);
            date0 = string(current_date_time.Year);
            if current_date_time.Month<10
                date0 = date0 + '/0'+string(current_date_time.Month);
            else
                date0 = date0 + '/' + string(current_date_time.Month);
            end
            date0 = date0 + '/' + string(current_date_time.Day);
            app.DateYYYMMDDEditField.Value = date0;
            updateSkyplotVars(app)
            updateplot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Pool diagram')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.OpcinDropDownLabel = uilabel(app.UIFigure);
            app.OpcinDropDownLabel.Text = 'Opción';
            app.OpcinDropDown = uidropdown(app.UIFigure);
            app.OpcinDropDown.Items = {'SE', 'NW'};
            app.OpcinDropDown.ValueChangedFcn = createCallbackFcn(app, @OpcinDropDownValueChanged, true);
            app.OpcinDropDown.Value = 'SE';
            app.SkyplotPanel = uipanel(app.UIFigure);
            app.SkyplotPanel.Title = 'Skyplot';
            app.Label = uilabel(app.SkyplotPanel);
            app.Label_2 = uilabel(app.SkyplotPanel);
            app.Label_3 = uilabel(app.SkyplotPanel);
            app.HeightmEditFieldLabel = uilabel(app.UIFigure);
            app.HeightmEditFieldLabel.Text = 'Height [m]';
            app.HeightmEditField = uieditfield(app.UIFigure, 'numeric');
            app.HeightmEditField.ValueChangedFcn = createCallbackFcn(app, @HeightmEditFieldValueChanged, true);
            app.DistancemEditFieldLabel = uilabel(app.UIFigure);
            app.DistancemEditFieldLabel.Text = 'Distance [m]';
            app.DistancemEditField = uieditfield(app.UIFigure, 'numeric');
            app.DistancemEditField.ValueChangedFcn = createCallbackFcn(app, @DistancemEditFieldValueChanged, true);
            app.DirectiondegEditFieldLabel = uilabel(app.UIFigure);
            app.DirectiondegEditFieldLabel.Text = 'Direction [deg]';
            app.DirectiondegEditField = uieditfield(app.UIFigure, 'numeric');
            app.DirectiondegEditField.ValueChangedFcn = createCallbackFcn(app, @DirectiondegEditFieldValueChanged, true);
            app.TimeofdayLabel = uilabel(app.UIFigure);
            app.TimeofdayLabel.Text = 'Time of day';
            app.hsEditFieldLabel = uilabel(app.UIFigure);
            app.hsEditFieldLabel.Text = 'hs';
            app.hsEditField = uieditfield(app.UIFigure, 'numeric');
            app.hsEditField.ValueChangedFcn = createCallbackFcn(app, @hsEditFieldValueChanged, true);
            app.minsEditFieldLabel = uilabel(app.UIFigure);
            app.minsEditFieldLabel.Text = 'mins';
            app.minsEditField = uieditfield(app.UIFigure, 'numeric');
            app.minsEditField.ValueChangedFcn = createCallbackFcn(app, @minsEditFieldValueChanged, true);
            app.secsEditFieldLabel = uilabel(app.UIFigure);
            app.secsEditFieldLabel.Text = 'secs';
            app.secsEditField = uieditfield(app.UIFigure, 'numeric');
            app.secsEditField.ValueChangedFcn = createCallbackFcn(app, @secsEditFieldValueChanged, true);
            app.DateYYYMMDDEditFieldLabel = uilabel(app.UIFigure);
            app.DateYYYMMDDEditFieldLabel.Text = 'Date (YYY/MM/DD)';
            app.DateYYYMMDDEditField = uieditfield(app.UIFigure, 'text');
            app.DateYYYMMDDEditField.ValueChangedFcn = createCallbackFcn(app, @DateYYYMMDDEditFieldValueChanged, true);
            app.CurrenttimeButton = uibutton(app.UIFigure, 'push');
            app.CurrenttimeButton.ButtonPushedFcn = createCallbackFcn(app, @CurrenttimeButtonPushed, true);
            app.CurrenttimeButton.Text = 'Current time';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GNSS_R_pool_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end